classdef ADCSGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ConnectTab                  matlab.ui.container.Tab
        DevicesListBoxLabel         matlab.ui.control.Label
        DevicesListBox              matlab.ui.control.ListBox
        ScanButton                  matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        DeviceIDLabel               matlab.ui.control.Label
        DisconnectButton            matlab.ui.control.Button
        MonitorTab                  matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        AngleTab                    matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        TimeEditFieldLabel          matlab.ui.control.Label
        TimeEditField               matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        YawEditField                matlab.ui.control.NumericEditField
        StopButton                  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        AngularVelocityTab          matlab.ui.container.Tab
        SpeedControlTab             matlab.ui.container.Tab
        ApplyButton                 matlab.ui.control.Button
        ExecuteButton               matlab.ui.control.Button
        DesiredSpeedEditFieldLabel  matlab.ui.control.Label
        DesiredSpeedEditField       matlab.ui.control.NumericEditField
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        idleLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        BT % BlueTooth Data
        stop % Description
        ax % Description
        angleGraph % Description
    end
    
    methods (Access = public)
        
        function LoadingStatus(app, message)
            app.idleLabel.Text = message;
            app.ScanButton.Enable = 'off';
            app.DevicesListBox.Enable = 'off';
            app.StatusLamp.Color = [1, 0.77, 0];
        end
    
        function LoadingComplete(app, message)
            app.idleLabel.Text = message;
            app.ScanButton.Enable = 'on';
            app.DevicesListBox.Enable = 'on';
            app.StatusLamp.Color = 'g';
        end
        
        function ReadData(app)           
            while ~app.stop
                data    = fread(app.BT, 7, 'float');
                %timing  = data(1);
                yaw       = data(3);
                % Plot Data
                app.Label.Text = num2str(yaw);
                app.YawEditField.Value = (yaw);
                drawnow;
                %plot(app.UIAxes, datenum( timing ), yaw );
                %app.UIAxes.XLim = datenum([timing-15 timing]);
                %refreshdata;
                %drawnow;
            end
        end
        
        function sendCommand(app, command)
            flushinput(app.BT);
            flushoutput(app.BT);
            command = hex2dec(command);
            fwrite(app.BT, int16(command), 'int16');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop = false;
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            LoadingStatus(app, 'Scanning for Available Bluetooth Devices');
            pause(0.2);
            BTdevices = instrhwinfo('bluetooth');
            app.DevicesListBox.Items = BTdevices.RemoteNames;
            app.DevicesListBox.ItemsData = BTdevices.RemoteIDs;
            LoadingComplete(app, 'idle');
        end

        % Value changed function: DevicesListBox
        function DevicesListBoxValueChanged(app, event)
            name = app.DevicesListBox.Value;
            app.DeviceIDLabel.Text = name;
            if(name)
                app.ConnectButton.Enable = 'on';
            else
                app.ConnectButton.Enable = 'off';
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            LoadingStatus(app, "Connecting To: " + app.DevicesListBox.Value);
            app.ConnectButton.Enable = 'off';
            delete(instrfind);
            pause(0.2);
            app.BT = Bluetooth(app.DevicesListBox.Value, 1); % Connect to device
            % Inint Stream Handler
            fopen(app.BT);
            
            flushinput(app.BT);
            flushoutput(app.BT);
            
            LoadingComplete(app, 'idle');
            app.DevicesListBox.Enable = 'off';
            app.ConnectButton.Visible = 'off';
            app.DisconnectButton.Visible = 'on';
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            LoadingStatus(app, "Disconnecting From: " + app.DevicesListBox.Value);
            fclose(app.BT);
            LoadingComplete(app, 'idle');
            app.DevicesListBox.Enable = 'on';
            app.ConnectButton.Visible = 'on';
            app.DisconnectButton.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %fclose(app.BT);
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            LoadingStatus(app, "Set Speed: " + app.DesiredSpeedEditField.Value + " RPM");
            % Send Set Speed Command
            if mod(length(dec2hex(app.DesiredSpeedEditField.Value)),2)
                desiredSpeedValueHex = horzcat('0',dec2hex(app.DesiredSpeedEditField.Value)) ;
            else
                desiredSpeedValueHex = dec2hex(app.DesiredSpeedEditField.Value) ;
            end
            %while 1
            command = {'02' desiredSpeedValueHex};
            sendCommand(app, command);
            %end
%             % Print & Verify Speed is Set to Serial Monitor
             %command = {'03' '00'};
             %sendCommand(app, command);
            LoadingComplete(app, 'idle');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Send Set Speed Command
            command = {'04' '01'};
            app.stop = false;
            sendCommand(app, command);
%             pause(10);
            app.ReadData();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Send Set Speed Command
            command = {'04' '02'};
            app.stop = true;
            sendCommand(app, command);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.DevicesListBoxLabel = uilabel(app.ConnectTab);
            app.DevicesListBoxLabel.Enable = 'off';
            app.DevicesListBoxLabel.Text = 'Devices';
            app.DevicesListBox = uilistbox(app.ConnectTab);
            app.DevicesListBox.Items = {};
            app.DevicesListBox.ValueChangedFcn = createCallbackFcn(app, @DevicesListBoxValueChanged, true);
            app.DevicesListBox.Enable = 'off';
            app.DevicesListBox.Value = {};
            app.ScanButton = uibutton(app.ConnectTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ConnectButton = uibutton(app.ConnectTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.DeviceIDLabel = uilabel(app.ConnectTab);
            app.DeviceIDLabel.Text = 'Device ID';
            app.DisconnectButton = uibutton(app.ConnectTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.MonitorTab = uitab(app.TabGroup);
            app.MonitorTab.Title = 'Monitor';
            app.TabGroup2 = uitabgroup(app.MonitorTab);
            app.AngleTab = uitab(app.TabGroup2);
            app.AngleTab.Title = 'Angle';
            app.UIAxes = uiaxes(app.AngleTab);
            title(app.UIAxes, 'Z Axis Angle')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Degree')
            app.StartButton = uibutton(app.AngleTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TimeEditFieldLabel = uilabel(app.AngleTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.AngleTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.AngleTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.AngleTab, 'numeric');
            app.StopButton = uibutton(app.AngleTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.AngleTab);
            app.AngularVelocityTab = uitab(app.TabGroup2);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.SpeedControlTab = uitab(app.TabGroup);
            app.SpeedControlTab.Title = 'Speed Control';
            app.ApplyButton = uibutton(app.SpeedControlTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ExecuteButton = uibutton(app.SpeedControlTab, 'push');
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.DesiredSpeedEditFieldLabel = uilabel(app.SpeedControlTab);
            app.DesiredSpeedEditFieldLabel.Text = 'Desired Speed';
            app.DesiredSpeedEditField = uieditfield(app.SpeedControlTab, 'numeric');
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.idleLabel = uilabel(app.UIFigure);
            app.idleLabel.Text = 'idle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ADCSGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end